classdef XipppyClientGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1           matlab.ui.Figure
        uitoolbar1        matlab.ui.container.Toolbar
        uitoggletool1     matlab.ui.container.toolbar.ToggleTool
        uitoggletool2     matlab.ui.container.toolbar.ToggleTool
        StartTraining     matlab.ui.control.Button
        LoadTrainingBtn   matlab.ui.control.Button
        FeatTxt           matlab.ui.control.Label
        KinTxt            matlab.ui.control.Label
        XhatTxt           matlab.ui.control.Label
        SensTxt           matlab.ui.control.Label
        DOFTable          matlab.ui.control.Table
        PythonTxt         matlab.ui.control.Label
        MatlabTxt         matlab.ui.control.Label
        StimTable         matlab.ui.control.Table
        SendParamsBtn     matlab.ui.control.Button
        ClearTblBtn       matlab.ui.control.Button
        GetStimParamsBtn  matlab.ui.control.Button
        CalStimTgl        matlab.ui.control.StateButton
        UserStimTable     matlab.ui.control.Table
        GetUsrStim        matlab.ui.control.Button
        SendUsrStimBtn    matlab.ui.control.Button
        BadElecTable      matlab.ui.control.Table
        SendBadElecs      matlab.ui.control.Button
        GetBadElecs       matlab.ui.control.Button
        CloseXSBtn        matlab.ui.control.Button
        StopStimBtn       matlab.ui.control.StateButton
        StopHandBtn       matlab.ui.control.StateButton
        ConnectedBtn      matlab.ui.control.Button
        PyTimeAxH         matlab.ui.control.UIAxes
        KinAxes           matlab.ui.control.UIAxes
        XhatAxes          matlab.ui.control.UIAxes
        SensAxes          matlab.ui.control.UIAxes
        FeatAxes          matlab.ui.control.UIAxes
        MTimeAxH          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        XC
        LogFID
        timer1
        BaseLoopTime
        MCalcTic
        MCalcTime
        MTotalTime
        LoopCnt
        BaseCnt
        MTimeBuff
        mH
        
        PyTimeBuff
        pyH
        
        FeatBuff
        FeatH
        CMap
        
        KinBuff
        KinH
        KinCMap
        
        XhatBuff
        XhatH
        XhatCMap
        
        SensLookup
        SensBuff
        SensMask
        SensH
        SensCMap
            
        
    end
    
    methods (Access = private)
        function LoadRecentWTSFcn(app, varargin)
            app.XC.write('LoadRecentWTS')
        end
        
        function closeFigure(app, varargin)
            if strcmp(app.timer1.Running,'off')
                app.XC.close;
                fclose(app.LogFID);
                delete(app.timer1);
            else
                stop(app.timer1);
            end
            delete(app);
        end
        
        function closeSystem(app, varargin)
            app.XC.close;
            fclose(app.LogFID);
            delete(app.timer1);
            delete(app);
        end
        
        function app = getNomadParams(app, varargin)            
            % get all the parameters needed to update GUI
            cmdstr = 'GetNomadParams';
            app.XC.write(cmdstr)
            while 1
                if ~isempty(app.XC.Event)
                    break;
                end
                pause(0.1);
            end
            EventStr = regexp(app.XC.Event,':','split','once');
            EventCmd = EventStr{2};
            if regexp(EventCmd,'shape')
                set(app.BadElecTable,'data',repmat({[]},15,1))
            else
                eval(EventCmd); % this populates the fields in the following lines.
                % EMG electrodes
                if isempty(bad_EMG_elecs)
                    set(app.BadElecTable,'data',repmat({[]},15,1))
                else
                    app.BadElecTable.Data = [num2cell(bad_EMG_elecs)'; repmat({[]},5,1)];
                end
                % kin
                app.DOFTable.Data(:,1) = kin;
                % lock_DOF
                app.DOFTable.Data(:,2) = lock_DOF(1:6);
                % mirror_DOF
                app.DOFTable.Data(:,3) = mirror_DOF(1:6);
                % stim params
                if any(any(stim_params))
                    app.StimTable.Data = [num2cell(stim_params); repmat({[]},5,9)];
                    app.UserStimTable.Data = updateUserTable(app, num2cell(stim_params(stim_params(:,8)==1,[1,2,4,5,9])),app.SensLookup);
                else
                    set(app.StimTable,'data',repmat({[]},15,9))
                    set(app.UserStimTable,'data',repmat({[]},15,5))
                end
                % stop_hand
                app.StopHandBtn.Value = stop_hand;
                app.CalStimTgl.Value = stop_hand;
                % stop stim
                app.StopStimBtn.Value = stop_stim;
            end
            app.XC.Event = [];
        end
        
        %timer loop
        function mainLoop(app, varargin)
            try
                app.MCalcTic = tic;
                if app.XC.Connected
                    if app.ConnectedBtn.BackgroundColor(1)==1
                        app.ConnectedBtn.BackgroundColor = [0,1,0];
                        % send updated time to XipppyServer
                        %         curTime = datestr(datetime('now'), 'dd mmm yyyy HH:MM:SS');
                        %         cmdStr = ['TimeUpdate:', curTime];
                        %         app.XC.write(cmdStr);
                        %         pause(0.15);
                        % request initial parameters from Nomad
                        getNomadParams(app);
                    end
                else
                    if app.ConnectedBtn.BackgroundColor(1)==0
                        app.ConnectedBtn.BackgroundColor = [1,0,0];
                    end
                end
                plotData(app);
                app.MCalcTime = toc(app.MCalcTic); %calculation time within loop
                app.MTotalTime = app.timer1.InstantPeriod; %overall loop time
                app.LoopCnt = app.timer1.TasksExecuted;
            catch ME
                assignin('base','ME',ME)
                fprintf('XipppyGUI: %s; name: %s; line: %0.0f\r\n',ME.message,ME.stack(1).name,ME.stack(1).line);
                stop(app.timer1);
            end
        end
        
        function plotData(app, varargin)
            app.FeatBuff(1:end-1,:) = app.FeatBuff(2:end,:);
            app.FeatBuff(end,:) = app.XC.Status.Features;
            for k=1:48
                set(app.FeatH(k),'ydata',app.FeatBuff(:,k))
            end
            
            app.KinBuff(1:end-1,:) = app.KinBuff(2:end,:);
            app.KinBuff(end,:) = app.XC.Status.Kinematics;
            for k=1:6
                set(app.KinH(k),'ydata',app.KinBuff(:,k))
            end
            
            app.XhatBuff(1:end-1,:) = app.XhatBuff(2:end,:);
            app.XhatBuff(end,:) = app.XC.Status.Xhat;
            for k=1:6
                set(app.XhatH(k),'ydata',app.XhatBuff(:,k))
            end
            
            app.SensBuff(1:end-1,:) = app.SensBuff(2:end,:);
            app.SensBuff(end,:) = app.XC.Status.Sensors;
            for k=1:19
                if app.SensMask(k)
                    set(app.SensH(k),'ydata',app.SensBuff(:,k),'visible','on')
                else
                    set(app.SensH(k),'visible','off');
                end
            end
            
            app.MTimeBuff(1:end-1,:) = app.MTimeBuff(2:end,:);
            app.MTimeBuff(end,:) = [app.MCalcTime,app.MTotalTime];
            set(app.mH(1),'ydata',app.MTimeBuff(:,1))
            set(app.mH(2),'ydata',app.MTimeBuff(:,2))
            
            app.PyTimeBuff(1:end-1,:) = app.PyTimeBuff(2:end,:);
            app.PyTimeBuff(end,:) = [app.XC.Status.CalcTimePy,app.XC.Status.ElapsedTimePy];
            set(app.pyH(1),'ydata',app.PyTimeBuff(:,1))
            set(app.pyH(2),'ydata',app.PyTimeBuff(:,2))
        end      
        
        function dcell = updateUserTable(~, dcell, lut)
            dcell(:,2) = lut([dcell{:,2}]);
        end
        
        function resetInteractions(app, event)
            % This function resets the states of the toggle tools that
            % impact user interactions.  It also resets the figure interactions.
             
            % Find all tools to reset.  Exclude the tool associated
            % with the event.
            interactiveTools = [app.uitoggletool1, app.uitoggletool2];
            interactiveTools(event.Source == interactiveTools) = [];
             
            % Set the state of the tools to 'off'.
            [interactiveTools.State] = deal('off');
             
            % Set figure interactions to 'off'.
            datacursormode(app.figure1, 'off')
            rotate3d(app.figure1, 'off');
            pan(app.figure1, 'off');
            zoom(app.figure1,'off');
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.DOFTable.BackgroundColor = [1 1 1];
            app.DOFTable.ColumnFormat = {[] [] []};
            app.StimTable.BackgroundColor = [1 1 1];
            app.StimTable.ColumnFormat = {'numeric' 'numeric' 'numeric' 'numeric' 'numeric' 'numeric' 'numeric' 'numeric' 'numeric'};
            app.UserStimTable.BackgroundColor = [1 1 1];
            app.UserStimTable.ColumnFormat = {'numeric' 'numeric' 'numeric' 'numeric' 'numeric' [] [] []};
            app.BadElecTable.BackgroundColor = [1 1 1];
            app.BadElecTable.ColumnFormat = {'numeric' [] [] [] [] [] [] [] []};
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, varargin)
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Setting figure properties
            set(app.figure1,'CloseRequestFcn',@app.closeFigure,'renderer','opengl')
            
            % Opening log file
            app.LogFID = fopen('XipppyClientGUI.txt','w+');
            
            % Initializing timing variables
            app.BaseLoopTime=0.066;
            app.MCalcTic = tic;
            app.MCalcTime = 0;
            app.MTotalTime = 0;
            app.LoopCnt = 1; %total loop iterations from start
            app.BaseCnt = 1;
            
            set(app.DOFTable,'data',zeros(6,3))
            set(app.StimTable,'data',repmat({[]},15,9))
            set(app.UserStimTable,'data',repmat({[]},15,5))
            set(app.BadElecTable,'data',repmat({[]},15,1))
            
            % Initializing loop time plot
            app.MTimeBuff = zeros(300,2); %for plotting loop speed
            app.mH = plot(app.MTimeAxH,app.MTimeBuff);
            set(app.MTimeAxH,'xlim',[1,300],'ylim',[0,0.08],'xtick',[],'xticklabel',[],'ytick',[],'yticklabel',[],'box','on')
            
            app.PyTimeBuff = zeros(300,2); %for plotting loop speed
            app.pyH = plot(app.PyTimeAxH,app.PyTimeBuff);
            set(app.PyTimeAxH,'xlim',[1,300],'ylim',[0,50],'xtick',[],'xticklabel',[],'ytick',[],'yticklabel',[],'box','on')
            
            % Initializing plot
            app.FeatBuff = zeros(300,48); %48 channels
            app.FeatH = plot(app.FeatAxes,app.FeatBuff);
            set(app.FeatAxes,'xlim',[1,300],'ylim',[-2000,2000],'xtick',[],'xticklabel',[],'box','on')
            app.CMap = flipud(jet(48));
            for k=1:48
                set(app.FeatH(k),'color',app.CMap(k,:))
            end
            disp(rendererinfo(app.FeatAxes))
            
            app.KinBuff = zeros(300,6);
            app.KinH = plot(app.KinAxes,app.KinBuff);
            set(app.KinAxes,'xlim',[1,300],'ylim',[-1.2,1.2],'xtick',[],'xticklabel',[],'box','on')
            app.KinCMap = flipud(jet(6));
            for k=1:6
                set(app.KinH(k),'color',app.KinCMap(k,:))
            end
            
            app.XhatBuff = zeros(300,6);
            app.XhatH = plot(app.XhatAxes,app.XhatBuff);
            set(app.XhatAxes,'xlim',[1,300],'ylim',[-1.2,1.2],'xtick',[],'xticklabel',[],'box','on')
            app.XhatCMap = flipud(lines(6));
            for k=1:6
                set(app.XhatH(k),'color',app.XhatCMap(k,:))
            end
            
            %1  = ind_lat (force)
            %2  = ind_tip (force)
            %3  = mid_tip (force)
            %4  = ring_tip (force)
            %5  = pinky_tip (force)
            %6  = palm_dist (force)
            %7  = palm_prox (force)
            %8  = hand_edge (force)
            %9  = hand_dorsal (force)
            %10 = thumb_ulnar (force)
            %11 = thumb_rad (force)
            %12 = thumb_vol (force)
            %13 = thumb_dor (force)
            %14 = wrist_pron (position)
            %15 = wrist_flex (position)
            %16 = ind_flex (position)
            %17 = mrp_flex (position)
            %18 = thumb_pitch (position)
            %19 = thumb_yaw (position)
            app.SensLookup = {'ind_lat','ind_tip','mid_tip','ring_tip','pinky_tip',...
                'palm_dist','palm_prox','hand_edge','hand_dorsal','thumb_ulnar',...
                'thumb_rad','thumb_vol','thumb_dor','wrist_pron','wrist_flex',...
                'ind_flex','mrp_flex','thumb_pitch','thumb_yaw'};
            app.SensBuff = zeros(300,19);
            app.SensMask = false(19,1);
            app.SensMask(1:13) = true;
            app.SensH = plot(app.SensAxes,app.SensBuff);
            set(app.SensAxes,'xlim',[1,300],'ylim',[-1.2,1.2],'xtick',[],'xticklabel',[],'box','on')
            app.SensCMap = flipud(jet(19));
            for k=1:19
                set(app.SensH(k),'color',app.SensCMap(k,:))
            end
            
            % Initializing timer
            delete(timerfindall);
            app.timer1 = timer;
            app.timer1.ExecutionMode = 'fixedRate';
            app.timer1.Period = app.BaseLoopTime;
            app.timer1.TasksToExecute = 1e12;
            app.timer1.BusyMode = 'queue';
            app.timer1.TimerFcn = @app.mainLoop;
            app.timer1.StopFcn = @app.closeSystem;
            
            % Initializing arduino communication
            app.XC = XipppyClient('lan'); pause(1);
%             if nargin > 1
%                 app.XC = XipppyClient(varargin{1});
%             else
%                 app.XC = XipppyClient;
%             end
            pause(5);
            
            start(app.timer1);
            pause(5);
        end

        % Value changed function: CalStimTgl
        function CalStimTglFcn(app, event)
            cmdstr = ['CalibrateStim:SS[''stop_hand''] = ', ...
                num2str(app.CalStimTgl.Value)];
            app.StopHandBtn.Value = app.CalStimTgl.Value;
            app.XC.write(cmdstr)
        end

        % Button pushed function: ClearTblBtn
        function ClearTblBtnFcn(app, event)
            set(app.StimTable,'data',repmat({[]},15,9))
            set(app.UserStimTable,'data',repmat({[]},15,5))
            cmdstr = 'UpdateStimParams:SS[''stim_params''] = np.array([]).reshape((0,9))';
            app.XC.write(cmdstr)
        end

        % Button pushed function: CloseXSBtn
        function CloseXSBtnFcn(app, event)
            cmdstr = 'close';
            app.XC.write(cmdstr)
        end

        % Cell edit callback: DOFTable
        function DOFTableFcn(app, event)
            kin = app.DOFTable.Data(:,1)'; kin(7) = 0;
            kinstr = regexprep(num2str(kin),'\s+',',');
            
            lock = app.DOFTable.Data(:,2)'; lock(7) = 0;
            lockstr = regexprep(num2str(lock),'\s+',',');
            
            mirror = app.DOFTable.Data(:,3)'; mirror(7) = 0;
            mirrorstr = regexprep(num2str(mirror),'\s+',',');
            
            cmdstr = ['UpdateDOF:SS[''kin''] = np.array([',kinstr,'],dtype=np.single);' ...
                'SS[''lock_DOF''] = np.array([',lockstr,'],dtype=bool);' ...
                'SS[''mirror_DOF''] = np.array([',mirrorstr,'],dtype=int);'];
            app.XC.write(cmdstr)
        end

        % Button pushed function: GetBadElecs
        function GetBadElecsFcn(app, event)
            cmdstr = 'GetBadElecs';
            app.XC.write(cmdstr)
            while 1
                if ~isempty(app.XC.Event)
                    break;
                end
                pause(0.1);
            end
            EventStr = regexp(app.XC.Event,':','split','once');
            EventCmd = EventStr{2};
            if regexp(EventCmd,'shape')
                set(app.BadElecTable,'data',repmat({[]},15,1))
            else
                eval(EventCmd);
                if isempty(data)
                    set(app.BadElecTable,'data',repmat({[]},15,1))
                else
                    app.BadElecTable.Data = [num2cell(data)'; repmat({[]},5,1)];
                end
            end
            app.XC.Event = [];
        end

        % Button pushed function: GetStimParamsBtn
        function GetStimParamsBtnFcn(app, event)
            cmdstr = 'GetStimParams';
            app.XC.write(cmdstr)
            while 1
                if ~isempty(app.XC.Event)
                    break;
                end
                pause(0.1);
            end
            EventStr = regexp(app.XC.Event,':','split','once');
            EventCmd = EventStr{2};
            if regexp(EventCmd,'shape')
                set(app.StimTable,'data',repmat({[]},15,9))
            else
                eval(EventCmd);
                app.StimTable.Data = [num2cell(data); repmat({[]},5,9)];
                app.UserStimTable.Data = updateUserTable(app, num2cell(data(data(:,8)==1,[1,2,4,5,9])),app.SensLookup);
            end
            app.XC.Event = [];
        end

        % Button pushed function: GetUsrStim
        function GetUsrStimFcn(app, event)
%             getNomadParams(app); % this should definitely not stay here..
            cmdstr = 'GetStimParams';
            app.XC.write(cmdstr)
            while 1
                if ~isempty(app.XC.Event)
                    break;
                end
                pause(0.1);
            end
            EventStr = regexp(app.XC.Event,':','split','once');
            EventCmd = EventStr{2};
            if regexp(EventCmd,'shape')
                set(app.StimTable,'data',repmat({[]},15,9))
            else
                eval(EventCmd);
                app.StimTable.Data = [num2cell(data); repmat({[]},5,9)];
                app.UserStimTable.Data = updateUserTable(app, num2cell(data(data(:,8)==1,[1,2,4,5,9])),app.SensLookup);
            end
            app.XC.Event = [];
        end

        % Button pushed function: LoadTrainingBtn
        function LoadTrainingBtnFcn(app, event)
            app.XC.write('LoadTraining')
        end

        % Button pushed function: SendBadElecs
        function SendBadElecsFcn(app, event)
            data = app.BadElecTable.Data;
            nonNan = cell2mat(cellfun(@(x) ~isnan(x), data, 'UniformOutput', false));
            data = data(nonNan);
            if all(reshape(cellfun(@isempty,data),[],1))
                cmdstr = 'UpdateBadElecs:SS[''bad_EMG_elecs''] = np.array([], dtype=int).reshape((0,1))';
            else
                cmdstr = ['UpdateBadElecs:SS[''bad_EMG_elecs''] = np.array([',sprintf('%d, ',int16(cell2mat(data))'),'], dtype=int)'];
            end
            app.XC.write(cmdstr)
        end

        % Button pushed function: SendParamsBtn
        function SendParamsBtnFcn(app, event)
            data = app.StimTable.Data;
            if all(reshape(cellfun(@isempty,data),[],1))
                cmdstr = 'UpdateStimParams:SS[''stim_params''] = np.array([]).reshape((0,9))';
            else
                cmdstr = ['UpdateStimParams:SS[''stim_params''] = np.array([',sprintf('[%d,%d,%d,%d,%d,%d,%d,%d,%d],',int16(cell2mat(data))'),'])'];
            end
            app.UserStimTable.Data = updateUserTable(app, data([data{:,8}] == 1, [1,2,4,5,9]),app.SensLookup);
            app.XC.write(cmdstr)
        end

        % Button pushed function: SendUsrStimBtn
        function SendUsrStimBtnFcn(app, event)
            data = app.UserStimTable.Data;
            if ~isempty(data)
                [~,idx] = ismember(data(:,2),app.SensLookup);
                data(:,2) = num2cell(idx);
                if ~all(reshape(cellfun(@isempty,data),[],1))
                    cmdstr = ['UpdateUserStimParams:SS[''stim_params''][np.ix_(SS[''stim_params''][:,7]==1, [3,4,8])] = np.array([',sprintf('[%d,%d,%d],',int16(cell2mat(data(:,3:5)))'),'])'];
                end
                app.StimTable.Data([app.StimTable.Data{:,8}] == 1, [1,2,4,5,9]) = data;
                app.XC.write(cmdstr)
            end
        end

        % Button pushed function: StartTraining
        function StartTrainingFcn(app, event)
            app.XC.write('StartTraining')
        end

        % Value changed function: StopHandBtn
        function StopHandBtnFcn(app, event)
            cmdstr = ['StopHand:SS[''stop_hand''] = ', ...
                num2str(app.StopHandBtn.Value)];
            app.CalStimTgl.Value = app.StopHandBtn.Value;
            app.XC.write(cmdstr)
        end

        % Value changed function: StopStimBtn
        function StopStimBtnFcn(app, event)
            cmdstr = ['StopStim:SS[''stop_stim''] = ', ...
                num2str(app.StopStimBtn.Value)];
            app.XC.write(cmdstr)
        end

        % Clicked callback: uitoggletool2
        function uitoggletool2_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable zoom-out based on the
            % tool's current state.
            state = app.uitoggletool2.State;
            zoomModeObject = zoom(app.figure1);
            if state
                zoomModeObject.Direction = 'out';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end

        % Clicked callback: uitoggletool1
        function uitoggletool1_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable zoom-in based on the
            % tool's current state.
            state = app.uitoggletool1.State;
            zoomModeObject = zoom(app.figure1);
            if state
                zoomModeObject.Direction = 'in';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'XipppyClientGUI';
            app.figure1.Tag = 'figure1';
            app.uitoolbar1 = uitoolbar(app.figure1);
            app.uitoolbar1.Tag = 'uitoolbar1';
            app.uitoggletool1 = uitoggletool(app.uitoolbar1);
            app.uitoggletool1.Tag = 'uitoggletool1';
            app.uitoggletool1.Tooltip = 'Zoom In';
            app.uitoggletool1.ClickedCallback = createCallbackFcn(app, @uitoggletool1_ClickedCallback, true);
            app.uitoggletool1.Icon = 'uitoggletool1_image.png';
            app.uitoggletool2 = uitoggletool(app.uitoolbar1);
            app.uitoggletool2.Tag = 'uitoggletool2';
            app.uitoggletool2.Tooltip = 'Zoom Out';
            app.uitoggletool2.ClickedCallback = createCallbackFcn(app, @uitoggletool2_ClickedCallback, true);
            app.uitoggletool2.Icon = 'uitoggletool2_image.png';
            app.StartTraining = uibutton(app.figure1, 'push');
            app.StartTraining.ButtonPushedFcn = createCallbackFcn(app, @StartTrainingFcn, true);
            app.StartTraining.Tag = 'StartTraining';
            app.StartTraining.Text = 'Start Training';
            app.LoadTrainingBtn = uibutton(app.figure1, 'push');
            app.LoadTrainingBtn.ButtonPushedFcn = createCallbackFcn(app, @LoadTrainingBtnFcn, true);
            app.LoadTrainingBtn.Tag = 'LoadTrainingBtn';
            app.LoadTrainingBtn.Enable = 'off';
            app.LoadTrainingBtn.Text = 'Load Training';
            app.FeatTxt = uilabel(app.figure1);
            app.FeatTxt.Tag = 'FeatTxt';
            app.FeatTxt.Text = 'Features';
            app.KinTxt = uilabel(app.figure1);
            app.KinTxt.Tag = 'KinTxt';
            app.KinTxt.Text = 'Kinematics';
            app.XhatTxt = uilabel(app.figure1);
            app.XhatTxt.Tag = 'XhatTxt';
            app.XhatTxt.Text = 'Xhat';
            app.SensTxt = uilabel(app.figure1);
            app.SensTxt.Tag = 'SensTxt';
            app.SensTxt.Text = 'Sensors';
            app.DOFTable = uitable(app.figure1);
            app.DOFTable.ColumnName = {'Pos'; 'Lock'; 'Mirror'};
            app.DOFTable.RowName = {'ThuFE'; 'IndFE'; 'MrpFE'; 'ThuInt'; 'WristFE'; 'WristRot'};
            app.DOFTable.ColumnEditable = [true true true];
            app.DOFTable.CellEditCallback = createCallbackFcn(app, @DOFTableFcn, true);
            app.DOFTable.Tag = 'DOFTable';
            app.PythonTxt = uilabel(app.figure1);
            app.PythonTxt.Tag = 'text12';
            app.PythonTxt.Text = 'Python';
            app.MatlabTxt = uilabel(app.figure1);
            app.MatlabTxt.Tag = 'text13';
            app.MatlabTxt.Text = 'Matlab';
            app.StimTable = uitable(app.figure1);
            app.StimTable.ColumnName = {'Chan'; 'SensIdx'; 'EncAlg'; 'MinAmp'; 'MaxAmp'; 'MinFreq'; 'MaxFreq'; 'Enable'; 'UsrEnable'};
            app.StimTable.ColumnEditable = [true true true true true true true true true];
            app.StimTable.Tag = 'StimTable';
            app.SendParamsBtn = uibutton(app.figure1, 'push');
            app.SendParamsBtn.ButtonPushedFcn = createCallbackFcn(app, @SendParamsBtnFcn, true);
            app.SendParamsBtn.Tag = 'SendParamsBtn';
            app.SendParamsBtn.Text = 'Send Stim Params';
            app.ClearTblBtn = uibutton(app.figure1, 'push');
            app.ClearTblBtn.ButtonPushedFcn = createCallbackFcn(app, @ClearTblBtnFcn, true);
            app.ClearTblBtn.Tag = 'ClearTblBtn';
            app.ClearTblBtn.Text = 'Clear Table';
            app.GetStimParamsBtn = uibutton(app.figure1, 'push');
            app.GetStimParamsBtn.ButtonPushedFcn = createCallbackFcn(app, @GetStimParamsBtnFcn, true);
            app.GetStimParamsBtn.Tag = 'GetStimParamsBtn';
            app.GetStimParamsBtn.Text = 'Get Stim Params';
            app.CalStimTgl = uibutton(app.figure1, 'state');
            app.CalStimTgl.ValueChangedFcn = createCallbackFcn(app, @CalStimTglFcn, true);
            app.CalStimTgl.Tag = 'CalStimTgl';
            app.CalStimTgl.Text = 'Calibrate Stim';
            app.UserStimTable = uitable(app.figure1);
            app.UserStimTable.ColumnName = {'Channel'; 'Sensor'; 'MinAmp'; 'MaxAmp'; 'UsrEnable'; ''; ''; ''};
            app.UserStimTable.ColumnEditable = [false false true true true false false false];
            app.UserStimTable.Tag = 'UserStimTable';
            app.GetUsrStim = uibutton(app.figure1, 'push');
            app.GetUsrStim.ButtonPushedFcn = createCallbackFcn(app, @GetUsrStimFcn, true);
            app.GetUsrStim.Tag = 'GetUsrStim';
            app.GetUsrStim.Text = 'Get User Stim';
            app.SendUsrStimBtn = uibutton(app.figure1, 'push');
            app.SendUsrStimBtn.ButtonPushedFcn = createCallbackFcn(app, @SendUsrStimBtnFcn, true);
            app.SendUsrStimBtn.Tag = 'SendUsrStimBtn';
            app.SendUsrStimBtn.Text = 'Send User Stim';
            app.BadElecTable = uitable(app.figure1);
            app.BadElecTable.ColumnName = {'Chan'; ''; ''; ''; ''; ''; ''; ''; ''};
            app.BadElecTable.ColumnEditable = [true false false false false false false false false];
            app.BadElecTable.Tag = 'BadElecTable';
            app.SendBadElecs = uibutton(app.figure1, 'push');
            app.SendBadElecs.ButtonPushedFcn = createCallbackFcn(app, @SendBadElecsFcn, true);
            app.SendBadElecs.Tag = 'SendBadElecs';
            app.SendBadElecs.Text = 'Send Bad Elecs';
            app.GetBadElecs = uibutton(app.figure1, 'push');
            app.GetBadElecs.ButtonPushedFcn = createCallbackFcn(app, @GetBadElecsFcn, true);
            app.GetBadElecs.Tag = 'GetBadElecs';
            app.GetBadElecs.Text = 'Get Bad Elecs';
            app.CloseXSBtn = uibutton(app.figure1, 'push');
            app.CloseXSBtn.ButtonPushedFcn = createCallbackFcn(app, @CloseXSBtnFcn, true);
            app.CloseXSBtn.Tag = 'CloseXSBtn';
            app.CloseXSBtn.Text = 'Close XipppyServer';
            app.StopStimBtn = uibutton(app.figure1, 'state');
            app.StopStimBtn.ValueChangedFcn = createCallbackFcn(app, @StopStimBtnFcn, true);
            app.StopStimBtn.Tag = 'StopStimBtn';
            app.StopStimBtn.Text = 'Stop Stim';
            app.StopHandBtn = uibutton(app.figure1, 'state');
            app.StopHandBtn.ValueChangedFcn = createCallbackFcn(app, @StopHandBtnFcn, true);
            app.StopHandBtn.Tag = 'StopHandBtn';
            app.StopHandBtn.Text = 'Stop Hand';
            app.ConnectedBtn = uibutton(app.figure1, 'push');
            app.ConnectedBtn.Tag = 'ConnectedBtn';
            app.ConnectedBtn.Text = 'Connected';
            app.PyTimeAxH = uiaxes(app.figure1);
            app.PyTimeAxH.XTick = [];
            app.PyTimeAxH.YTick = [];
            app.PyTimeAxH.Tag = 'PyTimeAxH';
            app.KinAxes = uiaxes(app.figure1);
            app.KinAxes.XTick = [];
            app.KinAxes.YTick = [];
            app.KinAxes.Tag = 'KinAxes';
            app.XhatAxes = uiaxes(app.figure1);
            app.XhatAxes.XTick = [];
            app.XhatAxes.YTick = [];
            app.XhatAxes.Tag = 'XhatAxes';
            app.SensAxes = uiaxes(app.figure1);
            app.SensAxes.XTick = [];
            app.SensAxes.YTick = [];
            app.SensAxes.Tag = 'SensAxes';
            app.FeatAxes = uiaxes(app.figure1);
            app.FeatAxes.XTick = [];
            app.FeatAxes.YTick = [];
            app.FeatAxes.YTickLabel = '';
            app.FeatAxes.Tag = 'FeatAxes';
            app.MTimeAxH = uiaxes(app.figure1);
            app.MTimeAxH.XTick = [];
            app.MTimeAxH.YTick = [];
            app.MTimeAxH.Tag = 'MTimeAxH';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = XipppyClientGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end